classdef Separation_aveugle_de_sources < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SparationaveugledesourcesUIFigure  matlab.ui.Figure
        ChoixdesourceMenu              matlab.ui.container.Menu
        Source1Menu                    matlab.ui.container.Menu
        Source2Menu                    matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        TextArea                       matlab.ui.control.TextArea
        GridLayout4                    matlab.ui.container.GridLayout
        Nomdelasource1TextArea         matlab.ui.control.TextArea
        Nomdelasource1TextAreaLabel    matlab.ui.control.Label
        Nomdelasource2TextArea         matlab.ui.control.TextArea
        Nomdelasource2TextAreaLabel    matlab.ui.control.Label
        Choixdelaffichage2ButtonGroup  matlab.ui.container.ButtonGroup
        Sourcespare2Button             matlab.ui.control.RadioButton
        Sourcespare1Button             matlab.ui.control.RadioButton
        Choixdelaffichage1ButtonGroup  matlab.ui.container.ButtonGroup
        Source2Button                  matlab.ui.control.RadioButton
        Source1Button                  matlab.ui.control.RadioButton
        PlotSourceSplit                matlab.ui.control.UIAxes
        PlotSource                     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function DisplayMessage(app, message)
            if strcmp(app.TextArea.Value,'')
                app.TextArea.Value = message;
            else
                app.TextArea.Value = [app.TextArea.Value(:)' , {message}];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global source1_name source1_data source2_name source2_data Fe Ns Nc N t;
            
            N = 40960;
            Ns = 2;
            Nc = 2;
            
            data = load('handel.mat');
            source1_name = 'handel.mat';
            source1_data = data.y(1:N,1);
            set(app.Nomdelasource1TextArea, 'Value','');
            set(app.Nomdelasource1TextArea, 'Value',source1_name);

            data = load('laughter.mat');
            source2_name = 'laughter.mat';
            source2_data = data.y(1:N,1);
            set(app.Nomdelasource2TextArea, 'Value','');
            set(app.Nomdelasource2TextArea, 'Value',source2_name);

            Fe = data.Fs;
            t = [0:N-1]/Fe;
            plot(app.PlotSource, t, source1_data);
        end

        % Menu selected function: Source1Menu
        function Source1MenuSelected(app, event)
            global source1_name source1_data Fe N t;
            name = uigetfile('.wav');
            [data,fe] = audioread(name);
            if fe~=Fe
                msgbox('Mauvaise fréquence d echantillonnage');
            else
                source1_name = name;
                source1_data = data(1:N,1);
                set(app.Nomdelasource1TextArea, 'Value','');
                set(app.Nomdelasource1TextArea, 'Value',source1_name);
            end
            plot(app.PlotSource, t, source1_data);
        end

        % Menu selected function: Source2Menu
        function Source2MenuSelected(app, event)
            global source2_name source2_data Fe N t;
            name = uigetfile('.wav');
            [data,fe] = audioread(name);
            if fe~=Fe
                msgbox('Mauvaise fréquence d echantillonnage');
            else
                source2_name = name;
                source2_data = data(1:N,1);
                set(app.Nomdelasource2TextArea, 'Value','');
                set(app.Nomdelasource2TextArea, 'Value',source2_name);
            end
            plot(app.PlotSource, t, source2_data);
        end

        % Selection changed function: Choixdelaffichage1ButtonGroup
        function Choixdelaffichage1ButtonGroupSelectionChanged(app, event)
            global source1_data source2_data t;
            selectedButton = app.Choixdelaffichage1ButtonGroup.SelectedObject;
            text = selectedButton.Text;
            if text=='Source 1'
                plot(app.PlotSource, t, source1_data);
            elseif text=='Source 2'
                plot(app.PlotSource, t, source2_data);
            end
            DisplayMessage(app, text);
        end

        % Selection changed function: Choixdelaffichage2ButtonGroup
        function Choixdelaffichage2ButtonGroupSelectionChanged(app, event)
            global source1_data source2_data t;
            selectedButton = app.Choixdelaffichage2ButtonGroup.SelectedObject;
            text = selectedButton.Text;
            if text=='Source séparée 1'
                plot(app.PlotSourceSplit, t, source1_data);
            elseif text=='Source séparée 2'
                plot(app.PlotSourceSplit, t, source2_data);
            end
            DisplayMessage(app, text);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SparationaveugledesourcesUIFigure = uifigure('Visible', 'off');
            app.SparationaveugledesourcesUIFigure.Name = 'Séparation aveugle de sources';
            app.SparationaveugledesourcesUIFigure.Icon = fullfile(pathToMLAPP, 'Calculator-Icon-Background-PNG-Image.png');
            app.ChoixdesourceMenu = uimenu(app.SparationaveugledesourcesUIFigure);
            app.ChoixdesourceMenu.Text = 'Choix de source';
            app.ChoixdesourceMenu.Tag = 'SourceMenu_Button';
            app.Source1Menu = uimenu(app.ChoixdesourceMenu);
            app.Source1Menu.MenuSelectedFcn = createCallbackFcn(app, @Source1MenuSelected, true);
            app.Source1Menu.Text = 'Source 1';
            app.Source1Menu.Tag = 'SourceMenu_Button1';
            app.Source2Menu = uimenu(app.ChoixdesourceMenu);
            app.Source2Menu.MenuSelectedFcn = createCallbackFcn(app, @Source2MenuSelected, true);
            app.Source2Menu.Text = 'Source 2';
            app.Source2Menu.Tag = 'SourceMenu_Button2';
            app.GridLayout = uigridlayout(app.SparationaveugledesourcesUIFigure);
            app.PlotSource = uiaxes(app.GridLayout);
            title(app.PlotSource, 'Source')
            xlabel(app.PlotSource, 'Temps (s)')
            ylabel(app.PlotSource, 'Amplitude')
            zlabel(app.PlotSource, 'Z')
            app.PlotSource.Tag = 'PlotSource';
            app.PlotSourceSplit = uiaxes(app.GridLayout);
            title(app.PlotSourceSplit, 'Source séparée')
            xlabel(app.PlotSourceSplit, 'Temps (s)')
            ylabel(app.PlotSourceSplit, 'Amplitude')
            zlabel(app.PlotSourceSplit, 'Z')
            app.PlotSourceSplit.Tag = 'PlotSourceSplit';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Choixdelaffichage1ButtonGroup = uibuttongroup(app.GridLayout2);
            app.Choixdelaffichage1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Choixdelaffichage1ButtonGroupSelectionChanged, true);
            app.Choixdelaffichage1ButtonGroup.Title = 'Choix de l''affichage 1';
            app.Choixdelaffichage1ButtonGroup.Tag = 'ChoixAffichage1';
            app.Source1Button = uiradiobutton(app.Choixdelaffichage1ButtonGroup);
            app.Source1Button.Tag = 'Source1_RadioButton1';
            app.Source1Button.Text = 'Source 1';
            app.Source1Button.Value = true;
            app.Source2Button = uiradiobutton(app.Choixdelaffichage1ButtonGroup);
            app.Source2Button.Tag = 'Source2_RadioButton1';
            app.Source2Button.Text = 'Source 2';
            app.Choixdelaffichage2ButtonGroup = uibuttongroup(app.GridLayout2);
            app.Choixdelaffichage2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Choixdelaffichage2ButtonGroupSelectionChanged, true);
            app.Choixdelaffichage2ButtonGroup.Title = 'Choix de l''affichage 2';
            app.Choixdelaffichage2ButtonGroup.Tag = 'ChoixAffichage2';
            app.Sourcespare1Button = uiradiobutton(app.Choixdelaffichage2ButtonGroup);
            app.Sourcespare1Button.Tag = 'SourceSplit1_RadioButton1';
            app.Sourcespare1Button.Text = 'Source séparée 1';
            app.Sourcespare1Button.Value = true;
            app.Sourcespare2Button = uiradiobutton(app.Choixdelaffichage2ButtonGroup);
            app.Sourcespare2Button.Tag = 'SourceSplit2_RadioButton1';
            app.Sourcespare2Button.Text = 'Source séparée 2';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.Nomdelasource2TextAreaLabel = uilabel(app.GridLayout4);
            app.Nomdelasource2TextAreaLabel.Text = 'Nom de la source 2';
            app.Nomdelasource2TextArea = uitextarea(app.GridLayout4);
            app.Nomdelasource2TextArea.Tag = 'textNameSource2';
            app.Nomdelasource2TextArea.Editable = 'off';
            app.Nomdelasource1TextAreaLabel = uilabel(app.GridLayout4);
            app.Nomdelasource1TextAreaLabel.Text = 'Nom de la source 1';
            app.Nomdelasource1TextArea = uitextarea(app.GridLayout4);
            app.Nomdelasource1TextArea.Tag = 'textNameSource1';
            app.Nomdelasource1TextArea.Editable = 'off';
            app.TextArea = uitextarea(app.GridLayout2);
            app.TextArea.Tag = 'TextArea';
            app.TextArea.Editable = 'off';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            % Show the figure after all components are created
            app.SparationaveugledesourcesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Separation_aveugle_de_sources

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SparationaveugledesourcesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SparationaveugledesourcesUIFigure)
        end
    end
end